classdef CR250interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        maintenanceMenu                 matlab.ui.container.Menu
        compileMexDriverMenuItem        matlab.ui.container.Menu
        configMenu                      matlab.ui.container.Menu
        openCR250MenuItem               matlab.ui.container.Menu
        deviceInfoMenuItem              matlab.ui.container.Menu
        syncMenuItem                    matlab.ui.container.Menu
        noneSyncMenuItem                matlab.ui.container.Menu
        manualSyncMenuItem              matlab.ui.container.Menu
        ntscSyncMenuItem                matlab.ui.container.Menu
        palSyncMenuItem                 matlab.ui.container.Menu
        cinemaSyncMenuItem              matlab.ui.container.Menu
        toggleEchoMenuItem              matlab.ui.container.Menu
        opsMenu                         matlab.ui.container.Menu
        takeMeasurementMenuItem         matlab.ui.container.Menu
        retrieveLastMesurementMenuItem  matlab.ui.container.Menu
        spectrumAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        theCR250dev 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('In startup')
            app.UIFigure.Name = 'CR250-interactive';
        end

        % Menu selected function: takeMeasurementMenuItem
        function takeMeasurement(app, event)
            app.theCR250dev.measure();
            app.theCR250dev.retrieveMeasurement();
        end

        % Menu selected function: compileMexDriverMenuItem
        function compileMexDriver(app, event)
            CR250dev.compileMexDriver();
        end

        % Menu selected function: openCR250MenuItem
        function openCR250(app, event)
            disp('Opening CR250')
            app.theCR250dev = CR250dev();
            app.theCR250dev.verbosity = 'min';
        end

        % Menu selected function: manualSyncMenuItem
        function setManualSyncMode(app, event)
            app.theCR250dev.syncMode = 'manual';
        end

        % Menu selected function: noneSyncMenuItem
        function setNoneSyncMode(app, event)
            app.theCR250dev.syncMode = 'none';
        end

        % Menu selected function: ntscSyncMenuItem
        function setNTSCSyncMode(app, event)
            app.theCR250dev.syncMode = 'NTSC';
        end

        % Menu selected function: palSyncMenuItem
        function setPALSyncMode(app, event)
            app.theCR250dev.syncMode = 'PAL';
        end

        % Menu selected function: cinemaSyncMenuItem
        function setCINEMASyncMode(app, event)
            app.theCR250dev.syncMode = 'CINEMA';
        end

        % Menu selected function: deviceInfoMenuItem
        function CR250info(app, event)
            app.theCR250dev.deviceConfig();
        end

        % Menu selected function: toggleEchoMenuItem
        function toggleEcho(app, event)
            app.theCR250dev.toggleEcho()
        end

        % Menu selected function: retrieveLastMesurementMenuItem
        function retieveLastMeasuement(app, event)
            app.theCR250dev.retrieveMeasurement();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.maintenanceMenu = uimenu(app.UIFigure);
            app.maintenanceMenu.Text = 'maintenance';
            app.compileMexDriverMenuItem = uimenu(app.maintenanceMenu);
            app.compileMexDriverMenuItem.MenuSelectedFcn = createCallbackFcn(app, @compileMexDriver, true);
            app.compileMexDriverMenuItem.Text = 'compile MEX driver';
            app.configMenu = uimenu(app.UIFigure);
            app.configMenu.Text = 'config';
            app.openCR250MenuItem = uimenu(app.configMenu);
            app.openCR250MenuItem.MenuSelectedFcn = createCallbackFcn(app, @openCR250, true);
            app.openCR250MenuItem.Text = 'open CR250';
            app.deviceInfoMenuItem = uimenu(app.configMenu);
            app.deviceInfoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CR250info, true);
            app.deviceInfoMenuItem.Text = 'CR250info';
            app.syncMenuItem = uimenu(app.configMenu);
            app.syncMenuItem.Text = 'sync mode';
            app.noneSyncMenuItem = uimenu(app.syncMenuItem);
            app.noneSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNoneSyncMode, true);
            app.noneSyncMenuItem.Text = 'none';
            app.manualSyncMenuItem = uimenu(app.syncMenuItem);
            app.manualSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setManualSyncMode, true);
            app.manualSyncMenuItem.Text = 'manual';
            app.ntscSyncMenuItem = uimenu(app.syncMenuItem);
            app.ntscSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNTSCSyncMode, true);
            app.ntscSyncMenuItem.Text = 'NTSC';
            app.palSyncMenuItem = uimenu(app.syncMenuItem);
            app.palSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setPALSyncMode, true);
            app.palSyncMenuItem.Text = 'PAL';
            app.cinemaSyncMenuItem = uimenu(app.syncMenuItem);
            app.cinemaSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setCINEMASyncMode, true);
            app.cinemaSyncMenuItem.Text = 'CINEMA';
            app.toggleEchoMenuItem = uimenu(app.configMenu);
            app.toggleEchoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @toggleEcho, true);
            app.toggleEchoMenuItem.Text = 'toggleEcho';
            app.opsMenu = uimenu(app.UIFigure);
            app.opsMenu.Text = 'ops';
            app.takeMeasurementMenuItem = uimenu(app.opsMenu);
            app.takeMeasurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @takeMeasurement, true);
            app.takeMeasurementMenuItem.Text = 'measure spectrum';
            app.retrieveLastMesurementMenuItem = uimenu(app.opsMenu);
            app.retrieveLastMesurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @retieveLastMeasuement, true);
            app.retrieveLastMesurementMenuItem.Text = 'retrieve last mesurement';
            app.spectrumAxes = uiaxes(app.UIFigure);
            xlabel(app.spectrumAxes, 'wavelength (nm)')
            ylabel(app.spectrumAxes, 'radiance')
            zlabel(app.spectrumAxes, 'Z')
            app.spectrumAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR250interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end